classdef EXIA_v13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EXIAUIFigure                   matlab.ui.Figure
        SFGStatusPanel                 matlab.ui.container.Panel
        ImportButton                   matlab.ui.control.Button
        DataListListBoxLabel           matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        Label                          matlab.ui.control.Label
        Reselect2                      matlab.ui.control.Button
        DashboardLabel                 matlab.ui.control.Label
        StatusTextArea                 matlab.ui.control.TextArea
        NewWindow                      matlab.ui.control.Button
        OutputTable                    matlab.ui.control.Table
        CopyAllButton                  matlab.ui.control.Button
        DataPanel                      matlab.ui.container.Panel
        Normalize                      matlab.ui.control.Button
        AverageButton                  matlab.ui.control.Button
        MultiplicationfactorEditFieldLabel  matlab.ui.control.Label
        MultiplicationfactorEditField  matlab.ui.control.NumericEditField
        CopyOutputOnlyButton           matlab.ui.control.Button
        DisplayButtonGroup             matlab.ui.container.ButtonGroup
        SFGButton                      matlab.ui.control.ToggleButton
        IRButton                       matlab.ui.control.ToggleButton
        VISButton                      matlab.ui.control.ToggleButton
        ApplyButton                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

% Author: Tieyi Lu, 05/11/2018,
% This matlab app is designed for processing SFG data in a straight forward fashion.
% Excel is not required for processing SFG anymore.
    properties (Access = public)
        number = 0;  % Description
        lasername = 0; 
        selpath = ''; % Directory for Files
        sfg=[];
        normalizedsfg=[]; 
        ir=[];
        vis=[];
        wavenumber = [];
        avg = [];
        normstats = 0; % For determining raw sfg or normalized sfg, 0:off, 1:on
        
        SelectedSFG = []; % Storage for Reselection
        SelectedIR = [];
        SelectedVis=[];
        SelectednormalizedSFG = [];
        SelectedIndex = [];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            value = app.MultiplicationfactorEditField.Value;
            app.avg = app.avg * value;
            app.OutputTable.Data = [app.wavenumber, app.avg];
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
            if isequal(app.normstats,0)
                app.avg = mean(app.SelectedSFG,2);
                plot(app.UIAxes, app.wavenumber, app.avg);
                app.StatusTextArea.Value = 'Average Completed.';
            elseif isequal(app.normstats,1)
                app.avg = mean(app.SelectednormalizedSFG,2);
                plot(app.UIAxes, app.wavenumber, app.avg);
                app.StatusTextArea.Value = 'Average Completed.';
            else
                msgbox('NO SFG SIGNAL DETECTED')
                return
            end
            app.OutputTable.Data = [app.wavenumber, app.avg];
        end

        % Button pushed function: CopyAllButton
        function CopyAllButtonPushed(app, event)
            
            %NUM2CLIP copies a numerical-array to the clipboard
            %   
            %   ARRAYSTRING = NUM2CLIP(ARRAY)
            %   
            %   Copies the numerical array ARRAY to the clipboard as a tab-separated
            %   string.  This format is suitable for direct pasting to Excel and other
            %   programs.
            %   
            %   The tab-separated result is returned as ARRAYSTRING.  This
            %   functionality has been included for completeness.
            %   
            %Author: Grigor Browning, modified by Tieyi to fit this EXIA.
            
        arraystring = num2str(app.OutputTable.Data); 
        arraystring(:,end+1) = char(10); %add a carrige return to the end of each row
            %reshape the array to a single line
            %note that the reshape function reshape is column based so to reshape by
            %rows one must use the inverse of the matrix
        arraystring = reshape(arraystring',1,prod(size(arraystring))); %reshape the array to a single line
            
        arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
        arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
            
            %now remove the additional space charaters - keeping a single space
            %charater after each 'numerical' entry
        arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
            
        arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
            
        clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
        app.StatusTextArea.Value = 'Copy All.';
        end

        % Button pushed function: CopyOutputOnlyButton
        function CopyOutputOnlyButtonPushed(app, event)
                    arraystring = num2str(app.OutputTable.Data(:,2)); 
        arraystring(:,end+1) = char(10); %add a carrige return to the end of each row
            %reshape the array to a single line
            %note that the reshape function reshape is column based so to reshape by
            %rows one must use the inverse of the matrix
        arraystring = reshape(arraystring',1,prod(size(arraystring))); %reshape the array to a single line
            
        arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
        arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
            
            %now remove the additional space charaters - keeping a single space
            %charater after each 'numerical' entry
        arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
            
        arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
            
        clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
        app.StatusTextArea.Value = 'Copy Output Only.';
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplayButtonGroup.SelectedObject;
            
            switch (selectedButton.Text)
                case 'SFG'
            %                     legendsequence = cell(1,app.number); % Create Legends
            
                    for i = 1:length(app.SelectedIndex)
                
                    tmp = strcat('R',num2str(app.SelectedIndex(i)));
                    legendsequence{i} = tmp;
                
                    plot(app.UIAxes,app.wavenumber,app.SelectedSFG(:,i),'-o');
                    hold(app.UIAxes,'on');
                    end
            
                    hleg1 = legend(app.UIAxes,legendsequence);
                    hold(app.UIAxes,'off');
                    app.normstats = 0; % Showing raw SFG
                case 'IR'
            %                     legendsequence = cell(1,app.number); % Create Legends
            
                    for i = 1:length(app.SelectedIndex)
                
                        tmp = strcat('R',num2str(app.SelectedIndex(i)));
                        legendsequence{i} = tmp;
                
                        plot(app.UIAxes,app.wavenumber,app.SelectedIR(:,i),'-o');
                        hold(app.UIAxes,'on');
                    end
            
                    hleg1 = legend(app.UIAxes,legendsequence);
                    hold(app.UIAxes,'off');
                    app.normstats = -1; % NO SFG
                case 'VIS'
            %                     legendsequence = cell(1,app.number); % Create Legends
            
                    for i = 1:length(app.SelectedIndex)
                
                        tmp = strcat('R',num2str(app.SelectedIndex(i)));
                        legendsequence{i} = tmp;
                
                        plot(app.UIAxes,app.wavenumber,app.SelectedVis(:,i),'-o');
                        hold(app.UIAxes,'on');
                    end
            
                    hleg1 = legend(app.UIAxes,legendsequence);
                    hold(app.UIAxes,'off');
                    app.normstats = -1; % No SFG 
            end    
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.StatusTextArea.Value = 'Selecting Files ......';
            
            [file,app.selpath,indx] = uigetfile({'*.sfg';'*.txt'},'Select Files','MultiSelect','on');
            app.StatusTextArea.Value = 'Getting Files ......';
            
            if iscell(file)
                app.sfg=[]; % Initialize the storage.
                app.ir=[];
                app.vis=[];
                app.normalizedsfg=[];
                app.wavenumber =[]; 
                
                file = cellstr(file);
                app.number = length(file); % Get the total number of imported files
                
                app.ListBox.Items = file;
                
                index = [];
                
                for i = 1:app.number %Creating Indices for each imported data
                    index = [index,i];
                end
                app.ListBox.ItemsData = index;
            
                app.StatusTextArea.Value = fullfile(app.selpath,file);
                 
            elseif file ~= 0 % not equal, only one spectrum
                app.sfg=[]; % Initialize the storage.
                app.ir=[];
                app.vis=[];
                app.normalizedsfg=[];
                app.wavenumber = [];
                
                file = cellstr(file);
                app.number = 1;
                app.ListBox.Items = cellstr(file); % Display char type data
                
                app.StatusTextArea.Value = [app.selpath,file];
                
            else 
                app.StatusTextArea.Value = 'User selected Cancel.';
                return
            end
            
            % Import Data
           
            legendsequence = cell(1,app.number);
            app.StatusTextArea.Value = 'Plotting Data ......';
            for i = 1:app.number
                % Create Legends
                tmp = strcat('R',num2str(i));
                legendsequence{i} = tmp;
                
                filename = fullfile(app.selpath,file{i}); % Get the exact full directory of the file


                switch (indx) % Detect the name of laser
                    case (1)    % SFG 3, WAVENUMBER a(j).data(:,1); raw SIGNAL a(j).data(:,2); VIS a(j).data(:,4); IR a(j).data(:,5)
                        app.lasername = 3;
                        a(i) = importdata(filename);    % temp variables for importing data
                        
                        app.wavenumber = a(1).data(:,1);  % Get WAVENUMBER:                         
                        app.sfg = [app.sfg a(i).data(:,2)]; % Get raw SFG signal
                        app.ir = [app.ir a(i).data(:,5)]; % Get IR 
                        app.vis = [app.vis a(i).data(:,4)]; % Get VIS 
                        
                        app.normalizedsfg = [app.normalizedsfg a(i).data(:,2) ./ (a(i).data(:,4) .* a(i).data(:,5)) ];
                        
                    case (2) % SFG 4, WAVENUMBMER (:,1); RAW SIGNAL (:,2); IR (:,3); VIS (:,4); NORMALIZED SIGNAL (:,5)
%                         app.lasername = 4;
%                         a.i = importdata(filename);
%                         
%                         app.wavenumber = a.i(:,1); % Get WAVENUMBER
%                         app.sfg = [app.sfg a.i(:,2)]; % Get SFG raw signal
%                         app.vis = [app.vis a.i(:,4)]; % Get VIS matrix
%                         app.ir = [app.ir a.i(:,3)];   % Get IR matrix
%                         app.normalizedsfg = [app.normalizedsfg a.i(:,5)]; % Get normalized SFG signal
                        app.lasername = 4;
                        a(i) = importdata(filename);    % temp variables for importing data
                        
                        app.wavenumber = a(1).data(:,1);  % Get WAVENUMBER:                         
                        app.sfg = [app.sfg a(i).data(:,2)]; % Get raw SFG signal
                        app.ir = [app.ir a(i).data(:,4)]; % Get IR 
                        app.vis = [app.vis a(i).data(:,3)]; % Get VIS 
                        
                        app.normalizedsfg = [app.normalizedsfg a(i).data(:,2) ./ (a(i).data(:,4) .* a(i).data(:,3)) ];
                end
                plot(app.UIAxes,app.wavenumber,app.sfg(:,i),'-o');
                hold(app.UIAxes,'on');
            end
            
            hleg1 = legend(app.UIAxes,legendsequence);
            hold(app.UIAxes,'off');
            app.Label.Text = ['SFG ' , num2str(app.lasername)]; % Display the name of laser
            app.StatusTextArea.Value = 'Import Completed.';
            
            app.SelectedSFG = app.sfg;  % Select All data after import
            app.SelectedIR = app.ir;
            app.SelectedVis = app.vis;
            app.SelectednormalizedSFG = app.normalizedsfg;
            
            app.normstats = 0; % Showing raw SFG , 06-15-2018 TY: Extra useless line?
        end

        % Button pushed function: NewWindow
        function NewWindowButtonPushed(app, event)
            selectedButton = app.DisplayButtonGroup.SelectedObject;
            switch (selectedButton.Text)
                case 'SFG'
            %                     legendsequence = cell(1,app.number); % Create Legends
            
                    for i = 1:length(app.SelectedIndex)
                
                    tmp = strcat('R',num2str(app.SelectedIndex(i)));
                    legendsequence{i} = tmp;
                
                    plot(app.wavenumber,app.SelectedSFG(:,i),'-o');
                    hold('on');
                    end
            
                    hleg1 = legend(legendsequence);
                    hold(app.UIAxes,'off');
                    app.normstats = 0; % Showing raw SFG
                case 'IR'
            %                     legendsequence = cell(1,app.number); % Create Legends
            
                    for i = 1:length(app.SelectedIndex)
                
                        tmp = strcat('R',num2str(app.SelectedIndex(i)));
                        legendsequence{i} = tmp;
                
                        plot(app.wavenumber,app.SelectedIR(:,i),'-o');
                        hold('on');
                    end
            
                    hleg1 = legend(legendsequence);
                    hold(app.UIAxes,'off');
                    app.normstats = -1; % NO SFG
                case 'VIS'
            %                     legendsequence = cell(1,app.number); % Create Legends
            
                    for i = 1:length(app.SelectedIndex)
                
                        tmp = strcat('R',num2str(app.SelectedIndex(i)));
                        legendsequence{i} = tmp;
                
                        plot(app.wavenumber,app.SelectedVis(:,i),'-o');
                        hold('on');
                    end
            
                    hleg1 = legend(legendsequence);
                    hold(app.UIAxes,'off');
                    app.normstats = -1; % No SFG 
            end    
        end

        % Button pushed function: Normalize
        function NormalizeButtonPushed(app, event)
            %             legendsequence = cell(1,app.number); % Create Legends
            
            for i = 1:length(app.SelectedIndex)
                
                tmp = strcat('R',num2str(app.SelectedIndex(i)));
                legendsequence{i} = tmp;
                
                plot(app.UIAxes,app.wavenumber,app.SelectednormalizedSFG(:,i),'-o');
                hold(app.UIAxes,'on');
            end
            
            hleg1 = legend(app.UIAxes,legendsequence);
            hold(app.UIAxes,'off');
            app.StatusTextArea.Value = 'Normalization Completed.';
            app.normstats = 1; % showing normalized SFG
        end

        % Button pushed function: Reselect2
        function Reselect2Pushed(app, event)
            app.SelectedIndex = app.ListBox.Value;
            disp(app.ListBox.Value)
            app.SelectedSFG = [];
            app.SelectedIR = [];
            app.SelectedVis=[];
            app.SelectednormalizedSFG=[];
            for i = 1:length(app.SelectedIndex)
                 app.SelectedSFG = [app.SelectedSFG, app.sfg(:,app.SelectedIndex(i))];
                 app.SelectedIR = [app.SelectedIR, app.ir(:,app.SelectedIndex(i))];
                 app.SelectedVis = [app.SelectedVis, app.vis(:,app.SelectedIndex(i))];
                 app.SelectednormalizedSFG = [app.SelectednormalizedSFG, app.normalizedsfg(:,app.SelectedIndex(i))];
                 
                 tmp = strcat('R',num2str(app.SelectedIndex(i)));
                 legendsequence{i} = tmp;
                 plot(app.UIAxes,app.wavenumber,app.SelectedSFG(:,i),'-o');
                 hold(app.UIAxes,'on');
            end        
                
            
            hleg1 = legend(app.UIAxes,legendsequence);
            hold(app.UIAxes,'off');
            app.StatusTextArea.Value = 'Reselection Complete.'
            app.normstats=0; % cancel the normalized status
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EXIAUIFigure = uifigure('Visible', 'off');
            app.EXIAUIFigure.Name = 'EXIA';
            app.SFGStatusPanel = uipanel(app.EXIAUIFigure);
            app.SFGStatusPanel.Title = 'SFG Status';
            app.ImportButton = uibutton(app.SFGStatusPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.DataListListBoxLabel = uilabel(app.SFGStatusPanel);
            app.DataListListBoxLabel.Text = 'Data List';
            app.ListBox = uilistbox(app.SFGStatusPanel);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.Label = uilabel(app.SFGStatusPanel);
            app.Label.Text = '';
            app.Reselect2 = uibutton(app.SFGStatusPanel, 'push');
            app.Reselect2.ButtonPushedFcn = createCallbackFcn(app, @Reselect2Pushed, true);
            app.Reselect2.Text = 'Reselect';
            app.DashboardLabel = uilabel(app.EXIAUIFigure);
            app.DashboardLabel.Text = 'Dashboard';
            app.StatusTextArea = uitextarea(app.EXIAUIFigure);
            app.StatusTextArea.Editable = 'off';
            app.StatusTextArea.Value = {'GN-001 Standing by.'};
            app.NewWindow = uibutton(app.EXIAUIFigure, 'push');
            app.NewWindow.ButtonPushedFcn = createCallbackFcn(app, @NewWindowButtonPushed, true);
            app.NewWindow.Text = 'Plot in New Window';
            app.OutputTable = uitable(app.EXIAUIFigure);
            app.OutputTable.ColumnName = {'Wavenumber'; 'Output'};
            app.OutputTable.RowName = {};
            app.OutputTable.ColumnEditable = [false true];
            app.CopyAllButton = uibutton(app.EXIAUIFigure, 'push');
            app.CopyAllButton.ButtonPushedFcn = createCallbackFcn(app, @CopyAllButtonPushed, true);
            app.CopyAllButton.Text = 'Copy All';
            app.DataPanel = uipanel(app.EXIAUIFigure);
            app.DataPanel.Title = 'Data ';
            app.Normalize = uibutton(app.DataPanel, 'push');
            app.Normalize.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.Normalize.Text = 'Normalize';
            app.AverageButton = uibutton(app.DataPanel, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.MultiplicationfactorEditFieldLabel = uilabel(app.EXIAUIFigure);
            app.MultiplicationfactorEditFieldLabel.Text = 'Multiplication factor';
            app.MultiplicationfactorEditField = uieditfield(app.EXIAUIFigure, 'numeric');
            app.CopyOutputOnlyButton = uibutton(app.EXIAUIFigure, 'push');
            app.CopyOutputOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyOutputOnlyButtonPushed, true);
            app.CopyOutputOnlyButton.Text = 'Copy Output Only';
            app.DisplayButtonGroup = uibuttongroup(app.EXIAUIFigure);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.SFGButton = uitogglebutton(app.DisplayButtonGroup);
            app.SFGButton.Text = 'SFG';
            app.SFGButton.Value = true;
            app.IRButton = uitogglebutton(app.DisplayButtonGroup);
            app.IRButton.Text = 'IR';
            app.VISButton = uitogglebutton(app.DisplayButtonGroup);
            app.VISButton.Text = 'VIS';
            app.ApplyButton = uibutton(app.EXIAUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.UIAxes = uiaxes(app.EXIAUIFigure);
            xlabel(app.UIAxes, 'wavenumber')
            % Show the figure after all components are created
            app.EXIAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EXIA_v13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EXIAUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EXIAUIFigure)
        end
    end
end